classdef old_reduct_displayer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        main_grid                       matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        reduction_manager               matlab.ui.container.Tab
        reduction_grid                  matlab.ui.container.GridLayout
        reduction_function              matlab.ui.control.DropDown
        reduction_dist_fun              matlab.ui.control.EditField
        reduction_source                matlab.ui.control.DropDown
        reduction_wv_win                matlab.ui.control.NumericEditField
        tsne_grid                       matlab.ui.container.GridLayout
        tsne_Algorithm                  matlab.ui.control.DropDown
        tSNEAlgorithmDropDownLabel      matlab.ui.control.Label
        tsne_LearnRate                  matlab.ui.control.NumericEditField
        ReductionWaveformwindowmsLabel_4  matlab.ui.control.Label
        tsne_perplexity                 matlab.ui.control.NumericEditField
        ReductionWaveformwindowmsLabel_3  matlab.ui.control.Label
        tsne_exaggeration               matlab.ui.control.NumericEditField
        ReductionWaveformwindowmsLabel_2  matlab.ui.control.Label
        tsne_dist_fun                   matlab.ui.control.DropDown
        DistMatDistancefunctionLabel    matlab.ui.control.Label
        RecductionfunctionLabel         matlab.ui.control.Label
        DistMatDistancefunctionEditFieldLabel  matlab.ui.control.Label
        ReductiononDropDownLabel        matlab.ui.control.Label
        reduction_norm_tree             matlab.ui.container.CheckBoxTree
        reduction_all_norm              matlab.ui.container.TreeNode
        reduction_z_norm                matlab.ui.container.TreeNode
        reduction_align_norm            matlab.ui.container.TreeNode
        ReductionWaveformwindowmsLabel  matlab.ui.control.Label
        cluster_manager                 matlab.ui.container.Tab
        cluster_grid                    matlab.ui.container.GridLayout
        kmean_grid                      matlab.ui.container.GridLayout
        kmean_online                    matlab.ui.control.DropDown
        kmeanOnlinePhaseDropDownLabel   matlab.ui.control.Label
        kmean_dist_fun                  matlab.ui.control.DropDown
        kmeanDistancefunctionLabel      matlab.ui.control.Label
        kmean_start                     matlab.ui.control.DropDown
        kmeanStartDropDownLabel         matlab.ui.control.Label
        kmean_replicates                matlab.ui.control.NumericEditField
        ReductionWaveformwindowmsLabel_7  matlab.ui.control.Label
        kmean_nClusters                 matlab.ui.control.NumericEditField
        ReductionWaveformwindowmsLabel_6  matlab.ui.control.Label
        kmean_MaxIter                   matlab.ui.control.NumericEditField
        ReductionWaveformwindowmsLabel_5  matlab.ui.control.Label
        HC_grid                         matlab.ui.container.GridLayout
        ReductionWaveformwindowmsLabel_11  matlab.ui.control.Label
        HC_clust_MaxClust               matlab.ui.control.NumericEditField
        HC_link_method                  matlab.ui.control.DropDown
        kmeanDistancefunctionLabel_2    matlab.ui.control.Label
        kmeanDistancefunctionLabel_3    matlab.ui.control.Label
        HC_clust_critera                matlab.ui.control.DropDown
        HC_clust_cutoff                 matlab.ui.control.NumericEditField
        ReductionWaveformwindowmsLabel_12  matlab.ui.control.Label
        dbscan_grid                     matlab.ui.container.GridLayout
        dbscan_dist_fun                 matlab.ui.control.DropDown
        DistMatDistancefunctionLabel_2  matlab.ui.control.Label
        dbscan_minpts                   matlab.ui.control.NumericEditField
        ReductionWaveformwindowmsLabel_10  matlab.ui.control.Label
        dbscan_epsilon                  matlab.ui.control.NumericEditField
        ReductionWaveformwindowmsLabel_9  matlab.ui.control.Label
        DistMatDistancefunctionEditField_2Label  matlab.ui.control.Label
        cluster_dist_fun                matlab.ui.control.EditField
        ClusterWaveformwindowmsEditFieldLabel  matlab.ui.control.Label
        cluster_wv_win                  matlab.ui.control.NumericEditField
        cluster_norm_tree               matlab.ui.container.CheckBoxTree
        cluster_all_norm                matlab.ui.container.TreeNode
        cluster_z_norm                  matlab.ui.container.TreeNode
        cluster_align_norm              matlab.ui.container.TreeNode
        cluster_source                  matlab.ui.control.DropDown
        ClusterOnLabel                  matlab.ui.control.Label
        cluster_method                  matlab.ui.control.DropDown
        StartMarkingButton              matlab.ui.control.StateButton
        ClusterMethodLabel              matlab.ui.control.Label
        WaveformsDisplayTab             matlab.ui.container.Tab
        display_grid                    matlab.ui.container.GridLayout
        disp_wv_win                     matlab.ui.control.NumericEditField
        DisplayWaveformwindowmsEditFieldLabel  matlab.ui.control.Label
        disp_type                       matlab.ui.control.DropDown
        DisplayTypeLabel                matlab.ui.control.Label
        ExportOptionsTab                matlab.ui.container.Tab
        export_grid                     matlab.ui.container.GridLayout
        GenerateCodeButton              matlab.ui.control.Button
        ExportDataButton                matlab.ui.control.Button
        SaveAppButton                   matlab.ui.control.Button
        reduction_axe                   matlab.ui.control.UIAxes
        cluster_disp_menu               matlab.ui.container.ContextMenu
        open_new_win                    matlab.ui.container.Menu
        main_scatter_menu               matlab.ui.container.ContextMenu
        show_on_signal                  matlab.ui.container.Menu
    end


    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %%%%%%%%%%%%%%%%%%%%%%%%  Properties %%%%%%%%%%%%%%%%%%%%%%%%
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %%%%%%%%%% Graphics holders %%%%%%%%%%
    properties (Access = private)
        % hold the main scatter, displaying the reduction & clustering results
        main_scatter matlab.graphics.chart.primitive.Scatter
        
        % hold a scatter that is under main_scatter, displaying what label
        % each point belong to
        label_scatter matlab.graphics.chart.primitive.Scatter
        
        % invisable scatters that are used for creating legend
        cluster_legend_scatter matlab.graphics.chart.primitive.Scatter
        label_legend_scatter matlab.graphics.chart.primitive.Scatter

        % hold the axes that display the waveforms
        cluster_disp_axes matlab.graphics.Graphics
    end

    %%%%%%%%%% Main Data Holder %%%%%%%%%%

    % properites that hold the main calculated data
    properties (Access = private)
        % the basic data to work on
        ied_obj IED.data
        
        % the data after reduction, in 2d - each col is a diffrent dim
        reduced_data

        % indecies for each row in base_data, marking what cluster it belong to
        % as a vector, with same number of rows as reduced_data
        cluster_idx

        % data labels - some conditions of intrest to place on each waveform.
        % cell label_types X 2, col 1 is label type name, col 2 is logical
        % vector - true for each waveform that answer the condition.
        wave_labels = {}

        % seed for each reduction & cluster run
        rng_seed = 0
    end
    

    % properties for user select cluster method "Manual" 
    properties (Access = private)
        manual_ROIs matlab.graphics.Graphics
        manual_draw_state = false;
        manual_instruct_show = true;
    end

    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %%%%%%%%%%%%%%%%%%%%%%%%%  Methods %%%%%%%%%%%%%%%%%%%%%%%%%%
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    %%%%%%%%%% Calculation methods %%%%%%%%%%
    methods (Access = private)
        
        function reduce_data(app)
            % perform reduction according to existing parameters in app.
            
            % make it reproducable in each run
            rng(app.rng_seed)

            %%%% collect data %%%%
            % display process dlg
            progress_dlg = uiprogressdlg(app.UIFigure,"Indeterminate","on","Message","Collecting data","Cancelable","off","Title","Reduce Data");
            cl = onCleanup(@() close(progress_dlg)); % remove progress_dlg block on any form of exit (i.e. error)
            base_wv = app.ied_obj.extract_discharges(app.reduction_wv_win.Value./1000);
            base_wv = double(base_wv);

            % normilased waveforms
            if ismember(app.reduction_norm_tree.CheckedNodes, app.reduction_z_norm)
                base_wv = zscore(base_wv,1,2);
            end
            if ismember(app.reduction_norm_tree.CheckedNodes, app.reduction_align_norm)
                base_wv = align_waveforms(base_wv);
            end
            
            % covert 2 distance matrix
            if app.reduction_source.Value == "Distance Mat"
                % collect distance function
                dist_fun = str2func(app.reduction_dist_fun.Value);
                
                % convert data to distance matrix
                data2reduce = wv2dist(base_wv, dist_fun);
                data2reduce = round(data2reduce,9); % to deal with double precision errors
            elseif app.reduction_source.Value == "Base Waveforms"
                data2reduce = base_wv;
            end

            %%%% switch reduction methods %%%%
            progress_dlg.Message = "Performing reduction";
            switch app.reduction_function.Value
                case 'PCA'
                    [~,scores] = pca(data2reduce,'Centered',false);
                    app.reduced_data = scores(:,1:2);

                case 't-SNE'
                    % convert distance function if needed, so tsne can use it
                    dist_fun = app.tsne_dist_fun.Value;
                    if contains(dist_fun,'@')
                        dist_fun = str2func(dist_fun);
                    end
                    % run tsne
                    app.reduced_data = tsne(data2reduce,...
                        'Distance',dist_fun,...
                        'Exaggeration', app.tsne_exaggeration.Value,...
                        'Perplexity', app.tsne_perplexity.Value,...
                        'Algorithm', app.tsne_Algorithm.Value,...
                        'LearnRate', app.tsne_LearnRate.Value);

                case 'cMDS'
                    scores = cmdscale(data2reduce);
                    app.reduced_data = scores(:,1:2);
            end

            %%%% Change Graphics %%%%
            progress_dlg.Message = "Updating Graphics";
            % note that we assume that for any reduction method, the number
            % of points is contant, and is equal to sum(app.ied_obj.accepted)
            % therefore we can update the x - y location without changing
            % clusters index
            app.main_scatter.XData = app.reduced_data(:,1);
            app.main_scatter.YData = app.reduced_data(:,2);
            
            close(progress_dlg)
            %%%% Recluster %%%%
            if app.cluster_source.Value == "Reduced Data 2d"
                % source data has changed - we should rerun the clustering
                % algorithem
                app.cluster_data()
            end

            % update label_scatter
            app.label_scatter.XData = app.reduced_data(:,1);
            app.label_scatter.YData = app.reduced_data(:,2);
        end
    

        function cluster_data(app)
            % preform clustering by existing parameters in app.
            % Change graphics to display new clusters.
            %   Note:
            %       app.cluster_idx usage assume that clusters are positive
            %       intergers without "jumps".
            
            % make it reproducable in each run
            rng(app.rng_seed)

            %%%% collect data %%%%
            progress_dlg = uiprogressdlg(app.UIFigure,"Indeterminate","on","Message","Collecting data","Cancelable","off","Title","Cluster Data");
            cl = onCleanup(@() close(progress_dlg)); % remove progress_dlg block on any form of exit (i.e. error)
            if app.cluster_source.Value == "Reduced Data 2d"
                data2cluster = app.reduced_data;
            else
                base_wv = app.ied_obj.extract_discharges(app.cluster_wv_win.Value/1000);
                base_wv = double(base_wv);

                % normilased waveforms
                if ismember(app.cluster_norm_tree.CheckedNodes, app.cluster_z_norm)
                    base_wv = zscore(base_wv,1,2);
                end
                if ismember(app.cluster_norm_tree.CheckedNodes, app.cluster_align_norm)
                    base_wv = align_waveforms(base_wv);
                end

                % covert 2 distance matrix
                if app.cluster_source.Value == "Distance Mat"
                    % collect distance function
                    dist_fun = str2func(app.cluster_dist_fun.Value);
                    
                    % convert data to distance matrix
                    data2cluster = wv2dist(base_wv, dist_fun);
                    data2cluster = round(data2cluster,9); % to deal with double precision errors
                elseif app.cluster_source.Value == "Base Waveforms"
                    data2cluster = base_wv;
                end
            end
            
            %%%% switch clustering methods %%%%
            progress_dlg.Message = "Performing clustering";
            switch app.cluster_method.Value
                case "kmeans"
                    app.cluster_idx = kmeans(data2cluster, app.kmean_nClusters.Value,...
                        'Distance',     app.kmean_dist_fun.Value, ...
                        'start',        app.kmean_start.Value, ...
                        'MaxIter',      app.kmean_MaxIter.Value, ...
                        'OnlinePhase',  app.kmean_online.Value, ...
                        'Replicates',   app.kmean_replicates.Value);
                
                case "dbscan"
                     % convert distance function if needed, so dbscan can use it
                    dist_fun = app.dbscan_dist_fun.Value;
                    if contains(dist_fun,'@')
                        dist_fun = str2func(dist_fun);
                    end
                    % run dbscan
                    app.cluster_idx = dbscan(data2cluster, app.dbscan_epsilon.Value, app.dbscan_minpts.Value, ...
                        "Distance", dist_fun);
                    app.cluster_idx(app.cluster_idx == -1) = max(app.cluster_idx)+1; % make outliers a seperate cluster
                
                case "hierarchical"
                    % can only be done on distance mat.
                    % data need to be transformed 2 vector so "linkage" will know that. 
                    data2cluster = squareform(data2cluster);

                    % Since distance mat already calculated, we will
                    % perform clustering by linking & clustering seperetly.
                    link_mat = linkage(data2cluster,app.HC_link_method.Value);

                    % split behavior by if we are using "MaxClust" or not
                    if isempty(app.HC_clust_MaxClust.Value)
                        app.cluster_idx = cluster(link_mat, ...
                            "criterion",    app.HC_clust_critera.Value,...
                            "cutoff",       app.HC_clust_cutoff.Value);
                    else
                        app.cluster_idx = cluster(link_mat, ...
                            "maxclust",     app.HC_clust_MaxClust.Value);
                    end

                case "Manual"
                    % Manual only work on reduced data, and clusters are
                    % calculated upon marking. No need to run this function
                    % in this case.
                case "None"
                    app.cluster_idx = ones(sum(app.ied_obj.accepted),1);
            end
            
            %%%% Change Graphics %%%%
            progress_dlg.Message = "Updating Grphics";
            
            % repaint dots
            app.main_scatter.CData = app.cluster_idx;
            
            % refresh legend
            app.refresh_colormap_legend()
            
            close(progress_dlg)
        end
  
    end
    
    %%%%%%%%%% Tab Management %%%%%%%%%%
    methods (Access = private)

        function build_reduce_tab(app)
            % remove & add elements from reduce tab according to changes in:
            % reduction_source
            % reduction_function
            %
            % the main idea is that there are base elements (identfied by
            % tag, 'base_elem', that 
            
            %%%% create base status - everything but base are hidden %%%%

            % display ALL elements
            all_child = app.reduction_grid.Children;
            app.reduction_grid.ColumnWidth = repelem({'fit'},numel(all_child));
            % hide existing non base-elements
            child2hide = all_child(~ismember(get(all_child,'Tag'),'base_elem'));
            cols2hide = unique(arrayfun(@(x) x.Layout.Column, child2hide));
            app.reduction_grid.ColumnWidth(cols2hide) = {0};
            
            % make all properties that can be enabled, enabled
            enable_able = findobj(all_child,'-property','Enable');
            set(enable_able,'Enable','on')
            
            %%%% change other fields according to existing parameters %%%%
            % This must happen here, so the right elements will be shown
            
            % change other fields according to existing parameters
            app.reduction_source.Enable = 'on';
            if app.reduction_function.Value == "cMDS"
                app.reduction_source.Value = "Distance Mat";
                app.reduction_source.Enable = 'off';
            end

            %%%% Reshow elements as needed %%%%

            % collect columns of elements to reshow
            col2show = [];
            switch app.reduction_source.Value
                case "Base Waveforms"
                    % No need to do anything
                case "Distance Mat"
                    col2show = [col2show app.reduction_dist_fun.Layout.Column];
            end
            switch app.reduction_function.Value
                case "PCA"
                    % No need to do anything
                case "t-SNE"
                    col2show = [col2show app.tsne_grid.Layout.Column];
                case "cMDS"
                    % No need to do anything
            end

            % reshow elements
            app.reduction_grid.ColumnWidth(unique(col2show)) = {'fit'};
        end


        function build_cluster_tab(app)
            % remove & add elements from cluster tab according to changes in:
            % cluster_source
            % cluster_method
            %
            % the main idea is that there are base elements (identfied by
            % tag, 'base_elem', that 
            
            %%%% create base status - everything but base are hidden %%%%

            % display ALL elements
            all_child = app.cluster_grid.Children;
            app.cluster_grid.ColumnWidth = repelem({'fit'},numel(all_child));
            % hide existing non base-elements
            child2hide = all_child(~ismember(get(all_child,'Tag'),'base_elem'));
            cols2hide = unique(arrayfun(@(x) x.Layout.Column, child2hide));
            app.cluster_grid.ColumnWidth(cols2hide) = {0};
            
            % make all properties that can be enabled, enabled
            enable_able = findobj(all_child,'-property','Enable');
            set(enable_able,'Enable','on')

            %%%% change other fields & disable according to existing parameters %%%%
            % This must happen here, so the right elements will be shown
            
            % with focuse on "cluster_method"
            if app.cluster_method.Value == "Manual"
                % manual only works on reduced data, since this is what displayed
                app.cluster_source.Value = "Reduced Data 2d";
                app.cluster_source.Enable = 'off';
                cols2hide = [app.cluster_wv_win.Layout.Column, app.cluster_norm_tree.Layout.Column];
                app.cluster_grid.ColumnWidth(cols2hide) = {0};

            elseif app.cluster_method.Value == "hierarchical"
                % hierarchical is seperated to 3 stages: creating distance mat, 
                % linkage & cluster. So source data is always distance matrix.
                app.cluster_source.Value = "Distance Mat";
                app.cluster_source.Enable = 'off';

            elseif app.cluster_method.Value == "dbscan" && ...
                    (app.dbscan_dist_fun.Value == "precomputed" || ...
                    (app.cluster_source.Value == "Distance Mat" && app.cluster_source.Enable == "on"))
                % in dbscan, if input is distance mat the distance function
                % must be "precomputed", and vise versa.
                % The "enable" condition on "cluster_source" is in order to
                % let user change the dist_fun & exit this state.
                app.cluster_source.Value = "Distance Mat";
                app.cluster_source.Enable = 'off';
                app.dbscan_dist_fun.Value = "precomputed";
            elseif app.cluster_method.Value == "None"
                % disable everything but cluster_method, as they are irrelevant
                set([app.cluster_dist_fun, app.cluster_wv_win, app.cluster_norm_tree, app.cluster_source],'Enable','off')
            end
            
            % with focuse on specific methods argumants
            if ~isempty(app.HC_clust_MaxClust.Value)
                % hierarchical clustering can't accept both
                % "MaxClust" & "critera - cutoff". 
                % Use empty state of HC_clust_MaxClust to diff between what
                % user wants.
                app.HC_clust_critera.Enable = "off";
                app.HC_clust_cutoff.Enable = "off";
            end

            %%%% Reshow elements as needed %%%%

            % collect columns of elements to reshow
            col2show = [];
            switch app.cluster_source.Value
                case "Reduced Data 2d"
                    % No need to do anything
                case "Base Waveforms"
                    % No need to do anything
                case "Distance Mat"
                    col2show = [col2show app.cluster_dist_fun.Layout.Column];
            end
            
            switch app.cluster_method.Value
                case "Manual"
                    col2show = [col2show app.StartMarkingButton.Layout.Column];
                case "kmeans"
                    col2show = [col2show app.kmean_grid.Layout.Column];
                case "dbscan"
                    col2show = [col2show app.dbscan_grid.Layout.Column];
                case "hierarchical"
                    col2show = [col2show app.HC_grid.Layout.Column];
            end

            % reshow elements
            app.cluster_grid.ColumnWidth(unique(col2show)) = {'fit'};
        end
        

        function update_wv_disp(app)
            % update the waveforms displays by requested label & 

            % delete old graphics
            delete(app.cluster_disp_axes)
            
            % collect base info
            [base_wv, t_wv] = app.ied_obj.extract_discharges(app.disp_wv_win.Value/1000);
            t_wv = t_wv*1000;
            base_wv = double(base_wv);
            nClusts = numel(unique(app.cluster_idx));
            base_elem_num = numel(findobj(app.display_grid,"Tag","base_elem"));
            
            % if there are to many clusters - offer to skip plotting
            if nClusts > 20
                answer = uiconfirm(app.UIFigure,...
                    "More than 20 diffrent clusters. " + ...
                    "Plotting all of them may take long and and cause memory issues, continue?",...
                    "Too many clusters","Icon","warning",...
                    "Options",["Yes","No"],"CancelOption","No","DefaultOption","No");
                if answer == "No"
                    return
                end
            end
            % collect labels data
            label_data = app.disp_type.Value;
            poss_labels = unique(label_data{2});
            nLabels = numel(poss_labels);

            % collect colors so they will match the clusters in main scatter
            colors = colormap(app.reduction_axe);

            % add graphics
            app.display_grid.ColumnWidth = ['fit', 'fit', repelem({'fit'},nClusts)];
            app.cluster_disp_axes = gobjects(nClusts,1);
            for iClust = nClusts:-1:1
                % add axis
                app.cluster_disp_axes(iClust) = uiaxes(app.display_grid);
                app.cluster_disp_axes(iClust).Layout.Column = iClust + base_elem_num;
                app.cluster_disp_axes(iClust).Layout.Row = [1 2];
                hold(app.cluster_disp_axes(iClust),"on")
                app.cluster_disp_axes(iClust).ContextMenu = app.cluster_disp_menu;

                %%%% How to plot %%%%
                
                % collect which values belong to this cluster, and disp_type data
                in_cluster = app.cluster_idx == iClust;
                
                % deal with special "Overlays" case
                if isscalar(label_data{1}) && label_data{1} == "Overlays"
                    % plot every waveform seperetly, on top of each other
                    plot(app.cluster_disp_axes(iClust),t_wv,base_wv(in_cluster,:)')
                    title(app.cluster_disp_axes(iClust), sprintf('Cluster %d',iClust))
                    continue
                end
                
                % create a unique color for each label
                cluster_color = rgb2hsv(colors(iClust,:));
                labels_colors = repmat(cluster_color,[nLabels,1]);
                % labels_colors(:,2) = normalize(labels_colors(:,2) + linspace(-0.15,0.15,nLabels),'range');
                if nLabels ~= 1
                    labels_colors(:,2) = linspace(0.25,1,nLabels);
                end
                labels_colors = hsv2rgb(labels_colors);

                % stdplot, sepereted by labels
                for iLabel = 1:nLabels
                    in_label = label_data{2} == poss_labels(iLabel);
                    wv2plot = base_wv(in_label & in_cluster,:);
                    
                    label_line(iLabel) = IED.utils.stdshade(app.cluster_disp_axes(iClust),wv2plot,0.5,labels_colors(iLabel,:),t_wv);
                end
                legend(app.cluster_disp_axes(iClust), label_line, label_data{1},'Location','best')
                
                title(app.cluster_disp_axes(iClust), sprintf('Cluster %d',iClust))
            end

            % make all axes have same ylimits
            all_ylims = vertcat(app.cluster_disp_axes.YLim);
            [app.cluster_disp_axes.YLim] = deal([ min(all_ylims(:,1)), max(all_ylims(:,2)) ]);
        end
    

        function main_scatter_ButtonDownFcn(app,~,evt)
            
            switch app.UIFigure.SelectionType
                case 'alt' % right mouse click
                    % collect all data points
                    x_data = app.main_scatter.XData;
                    y_data = app.main_scatter.YData;
                    z_data = app.main_scatter.ZData;
                    if isempty(z_data)
                        % this code always assume 3d - in case of 2d, just use 0.
                        z_data = zeros(size(y_data));
                    end
                    data_points = [x_data; y_data; z_data]';

                    % find the sample relating to this point in the original signal
                    [~,p_idx] = min(pdist2(data_points,evt.IntersectionPoint));
                    all_accepted = find(app.ied_obj.accepted);
                    relevant_sample = app.ied_obj.pos(all_accepted(p_idx));

                    % pass the sample info to uimenu function
                    app.show_on_signal.MenuSelectedFcn = @(~,~) app.show_on_signalMenuSelected(relevant_sample);

                    % open ui context menu
                    cp = app.UIFigure.CurrentPoint;
                    open(app.main_scatter_menu,cp)
                case 'normal' % left click
                    if isempty(app.main_scatter.ZData)
                        % 2d graph
                        datatip(app.main_scatter,evt.IntersectionPoint(1),evt.IntersectionPoint(2));
                    else
                        % 3d graph
                        datatip(app.main_scatter,evt.IntersectionPoint(1),evt.IntersectionPoint(2),evt.IntersectionPoint(3));
                    end
            end
        end


        function show_on_signalMenuSelected(app,relevant_sample)

            % convert all accepted detections to events
            detect_pos = app.ied_obj.pos(app.ied_obj.accepted);
            detect_t = detect_pos./app.ied_obj.fs;
            event_win = app.disp_wv_win.Value/1000;
            event_t = (detect_t + [-event_win/2 event_win/2])'; % start-end of each event, each event is a col

            % collect labels info
            label_data = app.disp_type.Value;
            poss_labels = unique(label_data{2});

            % seperate events by label, into event struct for event_marker
            for iLabel = 1:numel(poss_labels)
                relv_events = event_t(:,label_data{2} == poss_labels(iLabel));
                event_struct.(label_data{1}{iLabel}) = relv_events(:)'; % force row vec - note that order is correct because each event is a col
            end

            % open event_marker
            disp_time = (relevant_sample./app.ied_obj.fs) - 0.5; % start so the relevant event would be mid screen
            ev_app = event_marker(app.ied_obj.sig,"fs",app.ied_obj.fs,"event_time",event_struct,"jump2time",disp_time);
            ev_app.saveable = false;
        end
    
    end
    
    %%%%%%%%%% Graphics Controls %%%%%%%%%%
    methods (Access = private)
        
        function refresh_colormap_legend(app)
            % recreate invisable scatters to match current datasets

            % remove existing invisable sactters
            all_scatters = app.reduction_axe.Children;
            delete(all_scatters(~ismember(all_scatters, [app.main_scatter app.label_scatter])))
            
            % find how many legend entries
            nClusts = numel(unique(app.cluster_idx));
            nLabels = numel(app.disp_type.Value{1});
            
            if nLabels == 1
                % if only 1 label - do not display any labels.
                % this will cause the iLabel loop to be skipped.
                nLabels = 0;
            end
            % change colormap so clusters & labels will be distinct
            colors = distinguishable_colors(nClusts + nLabels,[1 1 1;0 0 0]);
            colormap(app.reduction_axe, colors);

            % add clusters invisable scatter
            for iClust = nClusts:-1:1
                scatter(app.reduction_axe, nan, nan, [], colors(iClust,:),'o','filled',...
                    'DisplayName',sprintf('Cluster %d',iClust));
            end
            
            % add labels invisable scatter
            for iLabel = nLabels:-1:1
                scatter(app.reduction_axe, nan, nan, [], colors(iLabel+nClusts,:),'o',...
                    'DisplayName',app.disp_type.Value{1}{iLabel});
            end
            
            % label_scatter.CData is indexing rows in colormap. Number of
            % clusters determine what is the right row to index - fix to
            % match
            app.label_scatter.CData = app.label_scatter.CData + nClusts;

            legend(app.reduction_axe,"Location","best")
        end
    
        function add_new_cluster_menu(app, evt)
            % Let user mark a new ROI
            if evt.Button == 3
                ROI_colors = distinguishable_colors(numel(app.manual_ROIs)+1);
                app.manual_ROIs(end+1) = drawfreehand(app.reduction_axe,"Color",ROI_colors(end,:));
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ied_obj, wave_labels, names, vals)
            % insert data into the app - call reduction method, 

            arguments (Input)
                app
                ied_obj IED.data
                wave_labels = {};
            end

            arguments (Input,Repeating)
                names char {mustBeTextScalar}
                vals
            end
            

            %%%% write in properites %%%%

            %%% ied obj
            app.ied_obj = ied_obj;
            
            %%% wave_labels

            % create defult waveforms disp_type ItemData
            app.disp_type.ItemsData = {...
                { {'Cluster'}, true(sum(ied_obj.accepted),1) }, ...
                { "Overlays", [] }...
                };

            % validate labels & add them in
            if ~isempty(wave_labels)

                % validate labels structure
                if ~iscell(wave_labels) || ~(ismatrix(wave_labels) && (size(wave_labels,2) == 3))

                    error('reduct_displayer:wave_labels',...
                        ['wave_labels must be cell matrix with 3 columns:\n' ...
                        '1) label type name\n2) which label each pos relate to\n3) each label meaning'])

                elseif ~iscellstr(wave_labels(:,1))

                    error('reduct_displayer:wave_labels','wave_labels 1st col must be cellstr, how to call each label type')

                elseif  (~all( ...
                        cellfun(@(x,y) isrow(x) && iscellstr(x) && numel(x)==numel(unique(y)),...
                        wave_labels(:,2), wave_labels(:,3)) ...
                        )) || ...
                        (~all( cellfun(@isvarname, [wave_labels{:,2}]) ))

                    error('reduct_displayer:wave_labels', ...
                        ['wave_labels 2st col must be cells containing row vec of cellstr,' ...
                        ' with same numebr of elements as there are unique labels in col 3 & are valid varnames (isvarname),' ...
                        ' what each label means (by order of appearance)'])

                elseif  ~all( cellfun(@(x) iscolumn(x) && numel(x)==sum(ied_obj.accepted), wave_labels(:,3)) )

                    error('reduct_displayer:wave_labels',...
                        ['wave_labels 3st col must be a col vector with the same number of elements as in ied.pos(ied.accepted),' ...
                        'which label each label belong to'])

                end

                % push into dropdown menu
                app.disp_type.Items = [(wave_labels(:,1)+" seperated")', app.disp_type.Items];
                app.disp_type.ItemsData = [num2cell(wave_labels(:,2:3),2)', app.disp_type.ItemsData];
            end
            
            %%% Override default dashbord values with user requests

            % validate only the right elements are refernced %

            % colllect setable elements
            dashbord_elem = [?matlab.ui.control.DropDown, ?matlab.ui.control.NumericEditField, ?matlab.ui.control.EditField, ?matlab.ui.container.TreeNode];
            class_info = ?reduct_displayer;
            props_in_dashbord = arrayfun(@(x) ~isempty(x.Validation) && ismember(x.Validation.Class,dashbord_elem), class_info.PropertyList);
            setable_props = class_info.PropertyList(props_in_dashbord);
            
            % find which elements are to be set
            [~,props2set] = ismember(names, {setable_props.Name});
            if any(props2set == 0)
                problem_names = join(names(props2set == 0),', ');
                error("Unrecognized name-value arguments: {%s}",problem_names)
            end

            % place value in the right place %
            for iName = 1:numel(props2set)

                % collect prop_data
                relv_class = setable_props(props2set(iName)).Validation.Class;
                val2use = vals{iName};
                prop_name = setable_props(props2set(iName)).Name;
                
                % set value by class
                switch relv_class
                    case ?matlab.ui.control.DropDown
                        % only accept scalar texts
                        validateattributes(val2use,{'char','string'},{'scalartext'},'reduct_displayer',prop_name)
                        
                        % find pos of element in drop down items, than set to that pos
                        drop_down_items = app.(prop_name).Items;
                        [~,val_pos] = ismember(val2use, drop_down_items);

                        % if the value exist, set to it, else error
                        if val_pos == 0
                            error('%s must be one of: %s',prop_name, join(drop_down_items,', ') )
                        else
                            app.(prop_name).ValueIndex = val_pos;
                        end

                    case ?matlab.ui.control.NumericEditField
                        % only accept scalar numerics
                        validateattributes(val2use,{'numeric'},{'scalar'},'reduct_displayer',prop_name)
                        
                        % validate that value is in limits, if not write it in
                        field_lims = app.(prop_name).Limits;
                        if val2use < field_lims(1) || val2use > field_lims(2)
                            warning("In %s, value must be between [%s]. Skipping",prop_name,join(string(field_lims),', '))
                        else
                            app.(prop_name).Value = val2use;
                        end
                        
                    case ?matlab.ui.control.EditField
                        % only accept scalar texts
                        validateattributes(val2use,{'char','string'},{'scalartext'},'reduct_displayer',prop_name)

                        % write in
                        app.(prop_name).Value = val2use;

                    case ?matlab.ui.container.TreeNode
                        % only accept scalar texts
                        validateattributes(val2use,{'numeric','logical'},{'binary'},'reduct_displayer',prop_name)

                        % find relevant tree
                        relv_tree = ancestor(app.(prop_name),'matlab.ui.container.CheckBoxTree');

                        % check / uncheck box
                        if val2use
                            relv_tree.CheckedNodes = [relv_tree.CheckedNodes app.(prop_name)];
                        else
                            relv_tree.CheckedNodes(ismember(relv_tree.CheckedNodes, app.(prop_name))) = [];
                        end
            
                end
            end
            
            %%% Preset graphics  %%%
            % create empty main scatter, so other functions can use it
            hold(app.reduction_axe,"on")
            
            % create main scatter, and its proparties
            app.main_scatter = scatter(app.reduction_axe,nan,nan,36,"filled","o","MarkerEdgeColor",'k','LineWidth',0.25);
            app.main_scatter.ContextMenu = app.main_scatter_menu;
            app.main_scatter.ButtonDownFcn = @(src,evt) app.main_scatter_ButtonDownFcn(src,evt);
            app.main_scatter.Annotation.LegendInformation.IconDisplayStyle = "off"; % prevent legend
            app.cluster_idx = ones(sum(ied_obj.accepted),1);
            
            % create empty label scatter
            app.label_scatter = scatter(app.reduction_axe,...
                nan(sum(ied_obj.accepted),1),nan(sum(ied_obj.accepted),1),...
                72,'filled',"o","PickableParts","none");
            uistack(app.label_scatter,"bottom")
            [~,~, app.label_scatter.CData] = unique(app.disp_type.Value{2});
            if numel(unique(app.disp_type.Value{2})) == 1
                % if current disp_type include a single label for all 
                % (i.e. "Full Cluster Average"), do not show the underlining labels.
                app.label_scatter.Visible = "off";
            end
            app.label_scatter.Annotation.LegendInformation.IconDisplayStyle = "off"; % prevent legend

            %%% Calculate reduction & clusters %%%
            app.reduce_data();
            if app.cluster_source.Value ~= "Reduced Data 2d"
                app.cluster_data();
            end

            %%% Build tabs %%%
            app.build_reduce_tab()
            app.build_cluster_tab()
        end

        % Callback function: HC_clust_MaxClust, HC_clust_critera, 
        % ...and 16 other components
        function refresh_clust(app, event)
            % Rebuilt cluster tab & recluster.
            % Order is important, as "build_cluster_tab" affect clustering
            % parameters.
            
            app.build_cluster_tab();
            app.cluster_data();
        end

        % Callback function: reduction_dist_fun, reduction_function, 
        % ...and 8 other components
        function refresh_reduction(app, event)
            % Rebuilt reduction tab & reduce.
            % Order is important, as "build_reduce_tab" affect reduction
            % parameters.
            % If app.cluster_source.Value is "Reduced Data 2d", reduce_data
            % will also cause reclustering.
            
            app.build_reduce_tab();
            app.reduce_data();
        end

        % Selection change function: TabGroup
        function build_wv_disp_tab(app, event)
            % plot waveforms only when waveform tab is open

            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == app.WaveformsDisplayTab
                app.update_wv_disp()
            end
        end

        % Value changed function: disp_wv_win
        function refresh_disp_tab(app, event)
            % replot waveforms on cluster_disp_axes
            app.update_wv_disp()
            
        end

        % Menu selected function: open_new_win
        function open_new_winMenuSelected(app, event)
            % open clicked axe in full window, unrealated to the app

            % collect what axe was clicked on
            current_axe = app.UIFigure.CurrentObject;

            % open a new figure
            new_fig = figure();

            % copy & reposition axe
            new_ax = copyobj(current_axe,new_fig);
            new_ax.Units = 'normalized';
            new_ax.Position = [0.0650    0.0550    0.8525    0.8965];

            % create legend for every line
            all_new_lines = findobj(new_ax,"Type","line");
            legend(new_ax,all_new_lines,"Location","best")
        end

        % Value changed function: disp_type
        function disp_typeValueChanged(app, event)
            % switch display to a new label setup.
            % Note that value{1} is each label meaning, value{2} is what
            % label match each waveform.
            
            % collect data
            label_data = app.disp_type.Value;
            
            % update label_scatter cdata to match new labels
            [~,~, app.label_scatter.CData] = unique(label_data{2},'stable');

            % if only 1 label, hide label_scatter
            if numel(label_data{1}) == 1
                app.label_scatter.Visible = "off";
            else
                app.label_scatter.Visible = "on";
            end
            
            % refresh legend
            app.refresh_colormap_legend()

            % refresh clusters waveforms display
            app.update_wv_disp()
        end

        % Value changed function: StartMarkingButton
        function StartMarkingButtonValueChanged(app, event)
            % let user mark clusters manually.
            % Note: this only work in 2d.
            
            value = app.StartMarkingButton.Value;
            
            % display instructions
            if value && app.manual_instruct_show
                answer = uiconfirm(app.UIFigure,...
                    ["Use right click anywhere on the axis background to start drawing a shape. " ...
                    "Hold the mouse till finish marking a cluster. " ...
                    "After relesing, you can edit / delete the existing shape (right click it), or add more clusters using right click on the axis. " ...
                    "When finished, deselect the 'Start Marking' button, and the clusters will be reevaluate. " ...
                    "Cancle by marking 0 clusters."...
                    "Note:" "1) In case of overlaps, the top ROI is used.","2) Points on the edge of the shape count as in it"],"Instructions",...
                    "Options",["Got it","Do not show again"],...
                    "CancelOption","Got it","DefaultOption","Got it","Icon","info");
                if answer == "Do not show again"
                    app.manual_instruct_show = false;
                end
            end

            if value
                % delete existing ROIs saved in memory
                delete(app.manual_ROIs)
                app.manual_ROIs = gobjects(0);

                % add right click create ROI to axes
                % app.reduction_axe.ContextMenu = app.manual_cluster_axe_menu;
                app.reduction_axe.ButtonDownFcn = @(~,evt) app.add_new_cluster_menu(evt);
            else
                % remove right click create ROI from axes
                % app.reduction_axe.ContextMenu = gobjects(0);
                app.reduction_axe.ButtonDownFcn = '';
                

                % if no ROIs - cancle operation (don't change anything)
                if isempty(app.manual_ROIs)
                    return
                end
                
                % make ROIs invisable
                [app.manual_ROIs.Visible] = deal("off");
                [app.manual_ROIs.HandleVisibility] = deal("off");

                % remove all existing cluster idx
                app.cluster_idx = nan(size(app.cluster_idx));

                % convert all ROIs to clusters
                % note that order is important - later drawn ROIs take
                % precedence over eariler
                xq = app.reduced_data(:,1);
                yq = app.reduced_data(:,2);
                for iROI = 1:numel(app.manual_ROIs)
                    % find what points are on / in ROI
                    ploy_pos = app.manual_ROIs(iROI).Position;
                    [p_in,p_on] = inpolygon(xq, yq, ploy_pos(:,1), ploy_pos(:,2));
                    
                    % change those points to new cluster index
                    app.cluster_idx(p_in | p_on) = iROI;
                end


                % place all points not in a cluster in a single cluster
                app.cluster_idx(isnan(app.cluster_idx)) = numel(app.manual_ROIs) + 1;
                
                % an empty ROI will create a "jump" in idx, causing weird
                % results. fix that.
                [~,~,app.cluster_idx] = unique(app.cluster_idx,"stable");
                
                % repaint dots
                app.main_scatter.CData = app.cluster_idx;
            
                % refresh legend
                app.refresh_colormap_legend()
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.main_grid = uigridlayout(app.UIFigure);
            app.reduction_axe = uiaxes(app.main_grid);
            title(app.reduction_axe, 'Title')
            xlabel(app.reduction_axe, 'X')
            ylabel(app.reduction_axe, 'Y')
            zlabel(app.reduction_axe, 'Z')
            app.TabGroup = uitabgroup(app.main_grid);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @build_wv_disp_tab, true);
            app.reduction_manager = uitab(app.TabGroup);
            app.reduction_manager.Title = 'Reduction Manager';
            app.reduction_grid = uigridlayout(app.reduction_manager);
            app.ReductionWaveformwindowmsLabel = uilabel(app.reduction_grid);
            app.ReductionWaveformwindowmsLabel.Tag = 'base_elem';
            app.ReductionWaveformwindowmsLabel.Text = {'Reduction'; 'Waveform window'; '[ms]'};
            app.reduction_norm_tree = uitree(app.reduction_grid, 'checkbox');
            app.reduction_norm_tree.Tag = 'base_elem';
            app.reduction_all_norm = uitreenode(app.reduction_norm_tree);
            app.reduction_all_norm.Text = 'All Normalisations';
            app.reduction_z_norm = uitreenode(app.reduction_all_norm);
            app.reduction_z_norm.Text = 'Z Score Waveforms';
            app.reduction_align_norm = uitreenode(app.reduction_all_norm);
            app.reduction_align_norm.Text = 'Align Waveforms';
            % Assign Checked Nodes
            app.reduction_norm_tree.CheckedNodesChangedFcn = createCallbackFcn(app, @refresh_reduction, true);
            app.ReductiononDropDownLabel = uilabel(app.reduction_grid);
            app.ReductiononDropDownLabel.Tag = 'base_elem';
            app.ReductiononDropDownLabel.Text = 'Reduction on:';
            app.DistMatDistancefunctionEditFieldLabel = uilabel(app.reduction_grid);
            app.DistMatDistancefunctionEditFieldLabel.Text = {'Dist Mat'; 'Distance function'};
            app.RecductionfunctionLabel = uilabel(app.reduction_grid);
            app.RecductionfunctionLabel.Tag = 'base_elem';
            app.RecductionfunctionLabel.Text = 'Reduction function:';
            app.tsne_grid = uigridlayout(app.reduction_grid);
            app.DistMatDistancefunctionLabel = uilabel(app.tsne_grid);
            app.DistMatDistancefunctionLabel.Tag = 'base_elem';
            app.DistMatDistancefunctionLabel.Text = {'t-SNE'; 'Distance function'};
            app.tsne_dist_fun = uidropdown(app.tsne_grid);
            app.tsne_dist_fun.Items = {'euclidean', 'seuclidean', 'fasteuclidean', 'fastseuclidean', 'cityblock', 'chebychev', 'minkowski', 'mahalanobis', 'cosine', 'correlation', 'spearman', 'hamming', 'jaccard'};
            app.tsne_dist_fun.Editable = 'on';
            app.tsne_dist_fun.ValueChangedFcn = createCallbackFcn(app, @refresh_reduction, true);
            app.tsne_dist_fun.Value = 'euclidean';
            app.ReductionWaveformwindowmsLabel_2 = uilabel(app.tsne_grid);
            app.ReductionWaveformwindowmsLabel_2.Text = {'t-SNE'; 'Exaggeration'};
            app.tsne_exaggeration = uieditfield(app.tsne_grid, 'numeric');
            app.tsne_exaggeration.Limits = [1 Inf];
            app.tsne_exaggeration.ValueChangedFcn = createCallbackFcn(app, @refresh_reduction, true);
            app.tsne_exaggeration.Value = 4;
            app.ReductionWaveformwindowmsLabel_3 = uilabel(app.tsne_grid);
            app.ReductionWaveformwindowmsLabel_3.Text = {'t-SNE'; 'Perplexity'};
            app.tsne_perplexity = uieditfield(app.tsne_grid, 'numeric');
            app.tsne_perplexity.Limits = [0 Inf];
            app.tsne_perplexity.ValueChangedFcn = createCallbackFcn(app, @refresh_reduction, true);
            app.tsne_perplexity.Value = 30;
            app.ReductionWaveformwindowmsLabel_4 = uilabel(app.tsne_grid);
            app.ReductionWaveformwindowmsLabel_4.Text = {'t-SNE'; 'LearnRate'};
            app.tsne_LearnRate = uieditfield(app.tsne_grid, 'numeric');
            app.tsne_LearnRate.Limits = [0 Inf];
            app.tsne_LearnRate.RoundFractionalValues = 'on';
            app.tsne_LearnRate.ValueChangedFcn = createCallbackFcn(app, @refresh_reduction, true);
            app.tsne_LearnRate.Value = 500;
            app.tSNEAlgorithmDropDownLabel = uilabel(app.tsne_grid);
            app.tSNEAlgorithmDropDownLabel.Text = {'t-SNE'; 'Algorithm'};
            app.tsne_Algorithm = uidropdown(app.tsne_grid);
            app.tsne_Algorithm.Items = {'exact', 'barneshut'};
            app.tsne_Algorithm.ValueChangedFcn = createCallbackFcn(app, @refresh_reduction, true);
            app.tsne_Algorithm.Value = 'exact';
            app.reduction_wv_win = uieditfield(app.reduction_grid, 'numeric');
            app.reduction_wv_win.Limits = [0 Inf];
            app.reduction_wv_win.ValueChangedFcn = createCallbackFcn(app, @refresh_reduction, true);
            app.reduction_wv_win.Tag = 'base_elem';
            app.reduction_wv_win.Value = 50;
            app.reduction_source = uidropdown(app.reduction_grid);
            app.reduction_source.Items = {'Base Waveforms', 'Distance Mat'};
            app.reduction_source.ValueChangedFcn = createCallbackFcn(app, @refresh_reduction, true);
            app.reduction_source.Tag = 'base_elem';
            app.reduction_source.Value = 'Base Waveforms';
            app.reduction_dist_fun = uieditfield(app.reduction_grid, 'text');
            app.reduction_dist_fun.ValueChangedFcn = createCallbackFcn(app, @refresh_reduction, true);
            app.reduction_dist_fun.Value = '@(x,y)1-max(abs(xcorr(x,y,"normalized")))';
            app.reduction_function = uidropdown(app.reduction_grid);
            app.reduction_function.Items = {'PCA', 't-SNE', 'cMDS'};
            app.reduction_function.ValueChangedFcn = createCallbackFcn(app, @refresh_reduction, true);
            app.reduction_function.Tag = 'base_elem';
            app.reduction_function.Value = 'PCA';
            app.cluster_manager = uitab(app.TabGroup);
            app.cluster_manager.Title = 'Cluster Manager';
            app.cluster_grid = uigridlayout(app.cluster_manager);
            app.ClusterMethodLabel = uilabel(app.cluster_grid);
            app.ClusterMethodLabel.Tag = 'base_elem';
            app.ClusterMethodLabel.Text = 'Cluster Method:';
            app.StartMarkingButton = uibutton(app.cluster_grid, 'state');
            app.StartMarkingButton.ValueChangedFcn = createCallbackFcn(app, @StartMarkingButtonValueChanged, true);
            app.StartMarkingButton.Text = 'Start Marking';
            app.cluster_method = uidropdown(app.cluster_grid);
            app.cluster_method.Items = {'Manual', 'kmeans', 'dbscan', 'hierarchical', 'None'};
            app.cluster_method.ValueChangedFcn = createCallbackFcn(app, @refresh_clust, true);
            app.cluster_method.Tag = 'base_elem';
            app.cluster_method.Value = 'kmeans';
            app.ClusterOnLabel = uilabel(app.cluster_grid);
            app.ClusterOnLabel.Tag = 'base_elem';
            app.ClusterOnLabel.Text = 'Cluster On:';
            app.cluster_source = uidropdown(app.cluster_grid);
            app.cluster_source.Items = {'Reduced Data 2d', 'Distance Mat', 'Base Waveforms'};
            app.cluster_source.ValueChangedFcn = createCallbackFcn(app, @refresh_clust, true);
            app.cluster_source.Tag = 'base_elem';
            app.cluster_source.Value = 'Reduced Data 2d';
            app.cluster_norm_tree = uitree(app.cluster_grid, 'checkbox');
            app.cluster_norm_tree.Tag = 'base_elem';
            app.cluster_all_norm = uitreenode(app.cluster_norm_tree);
            app.cluster_all_norm.Text = 'All Normalisations';
            app.cluster_z_norm = uitreenode(app.cluster_all_norm);
            app.cluster_z_norm.Text = 'Z Score Waveforms';
            app.cluster_align_norm = uitreenode(app.cluster_all_norm);
            app.cluster_align_norm.Text = 'Align Waveforms';
            % Assign Checked Nodes
            app.cluster_norm_tree.CheckedNodesChangedFcn = createCallbackFcn(app, @refresh_clust, true);
            app.cluster_wv_win = uieditfield(app.cluster_grid, 'numeric');
            app.cluster_wv_win.Limits = [0 Inf];
            app.cluster_wv_win.ValueChangedFcn = createCallbackFcn(app, @refresh_clust, true);
            app.cluster_wv_win.Tag = 'base_elem';
            app.cluster_wv_win.Value = 50;
            app.ClusterWaveformwindowmsEditFieldLabel = uilabel(app.cluster_grid);
            app.ClusterWaveformwindowmsEditFieldLabel.Tag = 'base_elem';
            app.ClusterWaveformwindowmsEditFieldLabel.Text = {'Cluster'; 'Waveform window'; '[ms]'};
            app.cluster_dist_fun = uieditfield(app.cluster_grid, 'text');
            app.cluster_dist_fun.ValueChangedFcn = createCallbackFcn(app, @refresh_clust, true);
            app.cluster_dist_fun.Value = '@(x,y)1-max(abs(xcorr(x,y,"normalized")))';
            app.DistMatDistancefunctionEditField_2Label = uilabel(app.cluster_grid);
            app.DistMatDistancefunctionEditField_2Label.Text = {'Dist Mat'; 'Distance function'};
            app.dbscan_grid = uigridlayout(app.cluster_grid);
            app.ReductionWaveformwindowmsLabel_9 = uilabel(app.dbscan_grid);
            app.ReductionWaveformwindowmsLabel_9.Text = {'dbscan'; 'epsilon'};
            app.dbscan_epsilon = uieditfield(app.dbscan_grid, 'numeric');
            app.dbscan_epsilon.Limits = [0 Inf];
            app.dbscan_epsilon.ValueChangedFcn = createCallbackFcn(app, @refresh_clust, true);
            app.dbscan_epsilon.Value = 3;
            app.ReductionWaveformwindowmsLabel_10 = uilabel(app.dbscan_grid);
            app.ReductionWaveformwindowmsLabel_10.Text = {'dbscan'; 'minpts'};
            app.dbscan_minpts = uieditfield(app.dbscan_grid, 'numeric');
            app.dbscan_minpts.Limits = [1 Inf];
            app.dbscan_minpts.RoundFractionalValues = 'on';
            app.dbscan_minpts.ValueChangedFcn = createCallbackFcn(app, @refresh_clust, true);
            app.dbscan_minpts.Value = 4;
            app.DistMatDistancefunctionLabel_2 = uilabel(app.dbscan_grid);
            app.DistMatDistancefunctionLabel_2.Tag = 'base_elem';
            app.DistMatDistancefunctionLabel_2.Text = {'dbscan'; 'Distance function'};
            app.dbscan_dist_fun = uidropdown(app.dbscan_grid);
            app.dbscan_dist_fun.Items = {'precomputed', 'euclidean', 'squaredeuclidean', 'seuclidean', 'mahalanobis', 'cityblock', 'minkowski', 'chebychev', 'cosine', 'correlation', 'hamming', 'jaccard', 'spearman'};
            app.dbscan_dist_fun.Editable = 'on';
            app.dbscan_dist_fun.ValueChangedFcn = createCallbackFcn(app, @refresh_clust, true);
            app.dbscan_dist_fun.Value = 'precomputed';
            app.HC_grid = uigridlayout(app.cluster_grid);
            app.ReductionWaveformwindowmsLabel_12 = uilabel(app.HC_grid);
            app.ReductionWaveformwindowmsLabel_12.Text = {'HC Cluster'; 'cutoff'};
            app.HC_clust_cutoff = uieditfield(app.HC_grid, 'numeric');
            app.HC_clust_cutoff.Limits = [0 Inf];
            app.HC_clust_cutoff.ValueChangedFcn = createCallbackFcn(app, @refresh_clust, true);
            app.HC_clust_cutoff.Value = 1;
            app.HC_clust_critera = uidropdown(app.HC_grid);
            app.HC_clust_critera.Items = {'inconsistent', 'distance'};
            app.HC_clust_critera.ValueChangedFcn = createCallbackFcn(app, @refresh_clust, true);
            app.HC_clust_critera.Value = 'inconsistent';
            app.kmeanDistancefunctionLabel_3 = uilabel(app.HC_grid);
            app.kmeanDistancefunctionLabel_3.Text = {'HC Cluster'; 'Criterion'};
            app.kmeanDistancefunctionLabel_2 = uilabel(app.HC_grid);
            app.kmeanDistancefunctionLabel_2.Text = {'HC Linkage'; 'Method'};
            app.HC_link_method = uidropdown(app.HC_grid);
            app.HC_link_method.Items = {'weighted', 'average', 'centroid', 'complete', 'median', 'single', 'ward'};
            app.HC_link_method.ValueChangedFcn = createCallbackFcn(app, @refresh_clust, true);
            app.HC_link_method.Value = 'weighted';
            app.HC_clust_MaxClust = uieditfield(app.HC_grid, 'numeric');
            app.HC_clust_MaxClust.Limits = [1 Inf];
            app.HC_clust_MaxClust.AllowEmpty = 'on';
            app.HC_clust_MaxClust.ValueChangedFcn = createCallbackFcn(app, @refresh_clust, true);
            app.HC_clust_MaxClust.Value = [];
            app.ReductionWaveformwindowmsLabel_11 = uilabel(app.HC_grid);
            app.ReductionWaveformwindowmsLabel_11.Text = {'HC Cluster'; 'MaxClust'};
            app.kmean_grid = uigridlayout(app.cluster_grid);
            app.ReductionWaveformwindowmsLabel_5 = uilabel(app.kmean_grid);
            app.ReductionWaveformwindowmsLabel_5.Text = {'kmean'; 'MaxIter'};
            app.kmean_MaxIter = uieditfield(app.kmean_grid, 'numeric');
            app.kmean_MaxIter.Limits = [0 Inf];
            app.kmean_MaxIter.RoundFractionalValues = 'on';
            app.kmean_MaxIter.ValueChangedFcn = createCallbackFcn(app, @refresh_clust, true);
            app.kmean_MaxIter.Value = 1000;
            app.ReductionWaveformwindowmsLabel_6 = uilabel(app.kmean_grid);
            app.ReductionWaveformwindowmsLabel_6.Text = {'kmean'; 'nClusters'};
            app.kmean_nClusters = uieditfield(app.kmean_grid, 'numeric');
            app.kmean_nClusters.Limits = [1 Inf];
            app.kmean_nClusters.RoundFractionalValues = 'on';
            app.kmean_nClusters.ValueChangedFcn = createCallbackFcn(app, @refresh_clust, true);
            app.kmean_nClusters.Value = 3;
            app.ReductionWaveformwindowmsLabel_7 = uilabel(app.kmean_grid);
            app.ReductionWaveformwindowmsLabel_7.Text = {'kmean'; 'Replicates'};
            app.kmean_replicates = uieditfield(app.kmean_grid, 'numeric');
            app.kmean_replicates.Limits = [1 Inf];
            app.kmean_replicates.RoundFractionalValues = 'on';
            app.kmean_replicates.ValueChangedFcn = createCallbackFcn(app, @refresh_clust, true);
            app.kmean_replicates.Value = 1;
            app.kmeanStartDropDownLabel = uilabel(app.kmean_grid);
            app.kmeanStartDropDownLabel.Text = {'kmean'; 'Start'};
            app.kmean_start = uidropdown(app.kmean_grid);
            app.kmean_start.Items = {'plus', 'cluster', 'sample', 'uniform'};
            app.kmean_start.ValueChangedFcn = createCallbackFcn(app, @refresh_clust, true);
            app.kmean_start.Value = 'plus';
            app.kmeanDistancefunctionLabel = uilabel(app.kmean_grid);
            app.kmeanDistancefunctionLabel.Text = {'kmean'; 'Distance function'};
            app.kmean_dist_fun = uidropdown(app.kmean_grid);
            app.kmean_dist_fun.Items = {'sqeuclidean', 'cityblock', 'cosine', 'correlation', 'hamming'};
            app.kmean_dist_fun.ValueChangedFcn = createCallbackFcn(app, @refresh_clust, true);
            app.kmean_dist_fun.Value = 'sqeuclidean';
            app.kmeanOnlinePhaseDropDownLabel = uilabel(app.kmean_grid);
            app.kmeanOnlinePhaseDropDownLabel.Text = {'kmean'; 'Online Phase'};
            app.kmean_online = uidropdown(app.kmean_grid);
            app.kmean_online.Items = {'On', 'Off'};
            app.kmean_online.ValueChangedFcn = createCallbackFcn(app, @refresh_clust, true);
            app.kmean_online.Value = 'On';
            app.WaveformsDisplayTab = uitab(app.TabGroup);
            app.WaveformsDisplayTab.Title = 'Waveforms Display';
            app.display_grid = uigridlayout(app.WaveformsDisplayTab);
            app.DisplayTypeLabel = uilabel(app.display_grid);
            app.DisplayTypeLabel.Text = 'Display Type:';
            app.disp_type = uidropdown(app.display_grid);
            app.disp_type.Items = {'Full Cluster Average', 'Overlays'};
            app.disp_type.ValueChangedFcn = createCallbackFcn(app, @disp_typeValueChanged, true);
            app.disp_type.Tag = 'base_elem';
            app.disp_type.Value = 'Full Cluster Average';
            app.DisplayWaveformwindowmsEditFieldLabel = uilabel(app.display_grid);
            app.DisplayWaveformwindowmsEditFieldLabel.Text = {'Display'; 'Waveform window'; '[ms]'};
            app.disp_wv_win = uieditfield(app.display_grid, 'numeric');
            app.disp_wv_win.Limits = [0 Inf];
            app.disp_wv_win.ValueChangedFcn = createCallbackFcn(app, @refresh_disp_tab, true);
            app.disp_wv_win.Tag = 'base_elem';
            app.disp_wv_win.Value = 50;
            app.ExportOptionsTab = uitab(app.TabGroup);
            app.ExportOptionsTab.Title = 'Export Options';
            app.export_grid = uigridlayout(app.ExportOptionsTab);
            app.SaveAppButton = uibutton(app.export_grid, 'push');
            app.SaveAppButton.Text = 'Save App';
            app.ExportDataButton = uibutton(app.export_grid, 'push');
            app.ExportDataButton.Text = 'Export Data';
            app.GenerateCodeButton = uibutton(app.export_grid, 'push');
            app.GenerateCodeButton.Text = 'Generate Code';
            app.cluster_disp_menu = uicontextmenu(app.UIFigure);
            app.open_new_win = uimenu(app.cluster_disp_menu);
            app.open_new_win.MenuSelectedFcn = createCallbackFcn(app, @open_new_winMenuSelected, true);
            app.open_new_win.Text = 'Open in New Window';
            app.main_scatter_menu = uicontextmenu(app.UIFigure);
            app.show_on_signal = uimenu(app.main_scatter_menu);
            app.show_on_signal.Text = 'Show On Signal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = old_reduct_displayer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end