classdef data_loader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        UITable             matlab.ui.control.Table
        instructions        matlab.ui.control.Label
        load_file           matlab.ui.control.Button
        load_var            matlab.ui.control.Button
        table_context_menu  matlab.ui.container.ContextMenu
        RemoveFromListMenu  matlab.ui.container.Menu
        ShowInExplorerMenu  matlab.ui.container.Menu
    end

    
    properties (Access = private)
        multi_load (1,1) matlab.lang.OnOffSwitchState % If to load multiple elements, or just 1
    end
    
    methods (Access = private)
        
        function get_files(app, filters)
            % collect files, add to table.
            % note that this can also pick a folder
            
            %[selected_files, selected_paths] = uigetfile(filters,"MultiSelect",app.multi_load); % option 2, less powerfull
            if app.multi_load
                selected_files = uipickfiles('Type',filters,'NumFiles',[0 inf]);
            else
                selected_files = uipickfiles('Type',filters,'NumFiles',[0 1]);
            end

            % check if something was collected
            if iscell(selected_files) && ~isempty(selected_files)
                % prevent adding the same file twice
                already_in = app.UITable.Data(app.UITable.Data.Source ~= "Workspace",["Source","Name","Type"]);
                if ~isempty(already_in)
                    already_in_files = fullfile(already_in.Source, join(already_in{:,["Name","Type"]},''));
                    selected_files(ismember(selected_files,already_in_files)) = [];
                end

                % seperate to columns
                [selected_paths, selected_files, file_types] = fileparts(selected_files);
                app.add2table(selected_paths, selected_files, file_types);
            end
        end


        function get_vars(app,filters)
            % collect vars, add to table
            [vars_names, vars_types] = data_loader_workspace.collect_vars('filters',filters,'multiselect',app.multi_load);
            
            % check if something was collected
            if ~isempty(vars_names)
                % prevent adding the same var twice
                already_in = app.UITable.Data.Name(app.UITable.Data.Source == "Workspace");
                vars_names(ismember(vars_names, already_in)) = [];
                vars_types(ismember(vars_names, already_in)) = [];

                % create matching source and push to table
                vars_source = repelem({'Workspace'}, numel(vars_names),1);
                app.add2table(vars_source, vars_names, vars_types);
            end
        end


        function add2table(app,in_sources, in_names, in_types)
            % add inputs to uitable.
            % assume that all in_* are the same size.

            % turn off unneeded warning
            warning('off','MATLAB:table:RowsAddedExistingVars')

            % add inputs to table
            if ~app.multi_load
                % remove existing
                app.UITable.Data(:,:) = [];
            end
            
            % collect where to add data
            nIn = numel(in_sources);
            pos2insert = height(app.UITable.Data)+1;
            pos2insert = pos2insert:(pos2insert+nIn-1);
            
            % init table with missing (and empty double cells)
            cell_cols = varfun(@class,app.UITable.Data,"OutputFormat","cell");
            cell_cols = strcmp(cell_cols,'cell');
            app.UITable.Data{pos2insert,~cell_cols} = deal(missing);
            
            % add in real data
            app.UITable.Data.Source(pos2insert) = in_sources;
            app.UITable.Data.Name(pos2insert) = in_names;
            app.UITable.Data.Type(pos2insert) = in_types;

            % calculate non-editable info cols
            app.update_non_editable_cols(pos2insert);

            % restore warning
            warning('on','MATLAB:table:RowsAddedExistingVars')

        end
        

        function update_non_editable_cols(app, rows2update)
            % update non_editable info cols when asked

            %collect cols for updating
            non_editable_cols = find(~app.UITable.ColumnEditable);
            non_editable_cols(1:3) = []; % remove base columns

            % collect functions & update
            for iCol = non_editable_cols
                col_fun = app.UITable.Data.Properties.CustomProperties.fill_fun{iCol};
                for iRow = rows2update
                    try
                        app.UITable.Data{iRow,iCol} = col_fun(app.UITable.Data(iRow,:));
                    catch
                        % function fail for some reason - place missing (or empty cell)
                        if ~iscell(app.UITable.Data{iRow,iCol})
                            app.UITable.Data{iRow,iCol} = missing;
                        else
                            app.UITable.Data{iRow,iCol} = {[]};
                        end
                    end
                end
            end

        end

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, info_types, info_names, info_datatypes, options)
            % DATA_LOADER - let user collect files & workspace vars in
            %               order to load them.
            %               When finished, the function call uiresume
            %               instead of deleting - this let user collect
            %               output, than close it independently.
            %
            % SYNTAX:
            %   data_loader(info_type, info_names, info_datatypes, options)
            %
            % INPUTS:
            %
            %   REPEATING: (required)
            %     Additional columns to display, to collect user-defined
            %     info about the objects to load, and to let user see
            %     calculated info about the objects.
            %
            %       info_types -     Type of information to display, either 'editable' or 'non_editable'.
            %
            %       info_names -     Names of the information columns to display in the UITable
            %                        must be text scalar.
            %
            %       info_datatypes - Data types for each information column. The format
            %                        varies based on the column type:
            %
            %         For 'editable' columns:
            %             - It should be 0 rows of the var 2 edit datatype.
            %             - Supported data types: numeric, logical, datetime, categorical,
            %               string, or cell.
            %             - For categorical columns, ensure correct specification of
            %               categories (e.g., categorical(string.empty, ["a","b"])).
            %             - Editable columns cannot have multiple subcolumns 
            %               (i.e - size(info_datatypes,2) must be 1). 
            %
            %         For 'non_editable' columns:
            %             - It should be a function handle, that take 1
            %               table row, and return a 1 row table-supported variable.
            %             - When called without any input, the function
            %               should return a 0 row output of the same type.
            %
            %   NAME-VALUE PAIRS: (optional)
            %     Loader parameters.
            %       
            %       'file_filters' -      Cell array specifying file filters, see uipickfiles TYPE option.
            %                             default: {'*', 'Any File'}.
            %
            %       'workspace_filters' - Function handle defining criteria for workspace variable inclusion.
            %                             See data_loader_workspace FILTERS FUNCTION section.
            %                             default: @(x) true(size(x,1),1).
            %
            %       'multiselect' -       Logical value determining whether loading multiple variable is allowed. 
            %                             If false, load only 1.
            %                             default: true.
            %
            % EXAMPLES:
            %
            %   % call with 2 editable infos, 1 non_editable info,
            %   % workspace & file filter:
            %   app = data_loader(...
            %    'editable', 'A', categorical(string.empty(),["a","b"]),...
            %    'editable', 'B', [], ...
            %    'non_editable', 'C', @non_ediable_test, ...
            %    'file_filters',{'*.m', 'MATLAB code'}, 'workspace_filters',@(x) strcmp(x.class, 'double') & x.size(1) > 1 ...
            %    )
            %
            %   % example for non_editable column info_datatypes function:
            %   % """
            %   function out = non_ediable_test(in_row)
            %       if nargin == 0
            %           out = repmat({''},0,1);
            %           return
            %       end
            %       
            %       if strcmp(in_row.Source,'Workspace')
            %           out = sprintf('Name: %s, A: %s',in_row.Name, in_row.A);
            %           out = {out};
            %       else
            %           error('must_be_var')
            %       end
            %   % """
            %   % Note that on error, the cell will be filled by missing or {[]}.
            %
            % NOTES:
            %   - When closing by close(app.UIFigure) (pressing X), the app
            %     simply call uiresume on the uifigure. This let user
            %     collect ouput on closing. Caller need to call delete(app.UIFigure)
            %     or delete(app) to close when finished.
            %
            %
            % DEPENDECIES:
            %   - DATA_LOADER_WORKSPACE
            %   - UIPICKFILES
            %
            % SEE ALSO:
            %   - UITABLE, DATA_LOADER_WORKSPACE, UIPICKFILES
            %
            % Author: Lior de Marcas (LdM) https://github.com/Liordemarcas
            % Date: 231217

            arguments
                app
            end
            arguments (Repeating)
                info_types  (1,:) char {mustBeTextScalar, mustBeMember(info_types,["editable","non_editable"])}
                info_names (1,:) char {mustBeTextScalar}
                info_datatypes % this can be:
                % editable - a 0 rows, 0/1 cols of to edit data type. Supported types are numeric, logical, datetime, categorical, string, or cellstr. For categorical, make sure categories are right (categorical(string.empty,["a","b"])).
                % non_editable - function handle that take a table row and return a 1 row output, and return a 0 row same number of cols as normal of output type for non-inputs call ( info_datatypes() ). Function will be evaluated by order given.
            end
            arguments
                options.file_filters (:,:)  cell {mustBeText} = {'*', 'Any File'};
                options.workspace_filters (1,1) function_handle = @(x) true(size(x,1),1);
                options.multiselect (1,1) logical {mustBeNumericOrLogical} = true;
            end

            %%%%%%%%%% insert elements to props %%%%%%%%
            app.multi_load = options.multiselect;

            %%%%%%%%%% add info columns %%%%%%%%%%
            editable_flag = strcmp(info_types,"editable");
            app.UITable.ColumnName = [app.UITable.ColumnName;info_names'];
            app.UITable.ColumnEditable = [app.UITable.ColumnEditable, editable_flag];
            app.UITable.ColumnSortable = true(numel(app.UITable.ColumnName, 1));

            % collect datatypes for each info col
            nInfo = numel(info_types);
            cols_datatypes = cell(1,nInfo);
            for iInfo = 1:nInfo
                if editable_flag(iInfo)
                    % editable - should be 0 rows of requested datatype
                    cols_datatypes{iInfo} = info_datatypes{iInfo};
                    
                    % validate 
                    if size(cols_datatypes{iInfo},2) > 1
                        error("Error at info: %d. Editable columns can't have multiple subcolumns.", iInfo)
                    elseif ~ismember(class(cols_datatypes{iInfo}), ["logical", "datetime", "categorical", "string"]) && ...
                            ~isnumeric(cols_datatypes{iInfo}) && ~iscell(cols_datatypes{iInfo})
                        error('Error at info: %d. Editable columns must be numeric, logical, datetime, categorical, string, or cell array of character vectors.', iInfo)
                    end
                else
                    % non_editable - should be a function that return a 0
                    % row of output type when called without inputs

                    % validate
                    if ~isa(info_datatypes{iInfo},"function_handle")
                        error("Error at info: %d. info_datatypes must be a function that result in 0 rows when called without inputs.",iInfo)
                    end
                    
                    % collect
                    cols_datatypes{iInfo} = info_datatypes{iInfo}();
                end
                
                % validate inputs
                if size(cols_datatypes{iInfo},1) ~= 0
                    error("Error at info: %d. info_datatypes must have 0 rows (editable) or a function that result in 0 rows when called without inputs. (non_editable)",iInfo)
                end

                if ischar(cols_datatypes{iInfo})
                    error("Error at info: %d. char is not supported. Use string or cellstr instead.",iInfo)
                end
            end
            % add base columns
            cols_datatypes = [{string.empty, string.empty, string.empty}, cols_datatypes];

            % add editable infos to instructions
            if any(editable_flag)
                text2add = join(info_names(editable_flag),', ');
                text2add = {['Edit cols {' text2add{:} '} with double click.']};
                app.instructions.Text = [app.instructions.Text(1); text2add; app.instructions.Text{2}];
            end

            %%%%%%%%%% create base uitable data %%%%%%%%

            % create table
            base_table = table(cols_datatypes{:});
            base_table.Properties.VariableNames = app.UITable.ColumnName';

            % write fill functions as a property
            base_table = addprop(base_table,"fill_fun","variable");
            info_datatypes(cellfun(@(x) ~isa(x,"function_handle"),info_datatypes)) = deal({[]});
            base_table.Properties.CustomProperties.fill_fun = [ {[], [], []}, info_datatypes];

            % push table into uitable
            app.UITable.Data = base_table;

            %%%%%%%%%%% add user info to buttons callbacks %%%%%%%%%%%
            app.load_var.ButtonPushedFcn = @(~,~) app.get_vars(options.workspace_filters);
            app.load_file.ButtonPushedFcn = @(~,~) app.get_files(options.file_filters);
            

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % update non_editable info cols upon any edit.
            
            indices = event.Indices;
            % newData = event.NewData;
            
            app.update_non_editable_cols(indices(1));
        end

        % Menu selected function: RemoveFromListMenu
        function RemoveFromListMenuSelected(app, event)
            % remove selected row.
            
            clicked_row = event.InteractionInformation.Row;
            app.UITable.Data(clicked_row,:) = [];
        end

        % Menu selected function: ShowInExplorerMenu
        function ShowInExplorerMenuSelected(app, event)
            % If row match a file, open file explorer and select the
            %   requested file (Only working for windows right now).
            % If row match a var, show on variable editor.

            % collect row info
            clicked_row = event.InteractionInformation.Row;

            % check if the row correspond to a file or var
            if strcmp(app.UITable.Data.Source{clicked_row},"Workspace")
                % input is a var
                
                % collect var
                var_name = app.UITable.Data.Name{clicked_row};
                
                % Before calling the openvar function from evalin, we'll do a
                % security check that the openvar function is the one provided
                % by Matlab.  This reduces the risk of using evalin().
                if ~contains(which('openvar'), fullfile(matlabroot,'toolbox'))
                    error('The built-in openvar function may be overshaddowed. Do not proceed.')
                end
                
                % open variable editor
                evalin("base",sprintf('openvar("%s")',var_name))

            else
                % input is a file

                % collect file path
                file_path = app.UITable.Data.Source{clicked_row};
                file_name = join(app.UITable.Data{clicked_row,["Name","Type"]},'');
                file_path = char(fullfile(file_path,file_name));
                
                % for security, validate that this result in a file
                if ~isfile(file_path)
                    error('Row does not lead to a file. Do not proceed.')
                end

                % open file explorer
                system(['explorer /select,"', file_path, '"']);
            end
        end

        % Context menu opening function: table_context_menu
        function table_context_menu_opening(app, event)
            % Prevent the opening of context menu if no row was selected

            % collect row info
            clicked_row = event.InteractionInformation.Row;
            
            % hide menus if no row found
            if isempty(clicked_row)
                app.RemoveFromListMenu.Visible = "off";
                app.ShowInExplorerMenu.Visible = "off";
            else
                app.RemoveFromListMenu.Visible = "on";
                app.ShowInExplorerMenu.Visible = "on";
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % call uiresume, to let user collect data. User need to close
            % app independently, via delete(app) or delete(app.UIFigure)

            uiresume(app.UIFigure)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.load_var = uibutton(app.GridLayout, 'push');
            app.load_var.Text = 'Load From Variable';
            app.load_file = uibutton(app.GridLayout, 'push');
            app.load_file.Text = 'Load From File';
            app.instructions = uilabel(app.GridLayout);
            app.instructions.Text = {'See what you choosed to load here.'; 'Close this window to finish loading.'};
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Source'; 'Name'; 'Type'};
            app.UITable.ColumnRearrangeable = 'on';
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true];
            app.UITable.ColumnEditable = [false false false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.table_context_menu = uicontextmenu(app.UIFigure);
            app.table_context_menu.ContextMenuOpeningFcn = createCallbackFcn(app, @table_context_menu_opening, true);
            app.RemoveFromListMenu = uimenu(app.table_context_menu);
            app.RemoveFromListMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveFromListMenuSelected, true);
            app.RemoveFromListMenu.Text = 'Remove From List';
            app.ShowInExplorerMenu = uimenu(app.table_context_menu);
            app.ShowInExplorerMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowInExplorerMenuSelected, true);
            app.ShowInExplorerMenu.Text = 'Show In Explorer';
            % Assign app.table_context_menu
            app.UITable.ContextMenu = app.table_context_menu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_loader(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end