classdef data_loader_workspace < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        instructions  matlab.ui.control.Label
        UITable       matlab.ui.control.Table
    end


    properties (Access = private)
        chossen_vars cell % variable names that user choose
    end
    
    methods (Access = public,Static)
        
        function [vars_names, vars_types] = collect_vars(options)
            % COLLECT_VARS - Collects information about selected variables using a MATLAB app.
            %
            %   [vars_names, vars_types] = collect_vars('multiselect', multiselect, 'filters', filters)
            %   opens the "data_loader_workspace" app for interactive variable selection and
            %   returns the names and data types of selected variables.
            %
            % INPUTS:
            %   'multiselect' - Logical, allows selecting multiple variables (default: true).
            %   'filters'     - Function handle defining variable filtering criteria
            %                   (default: @(x) true(size(x,1),1)). See "FILTERS FUNCTION"
            %                   section under "DATA_LOADER_WORKSPACE" for details.  
            %
            % OUTPUTS:
            %   vars_names - Cell array of selected variable names.
            %   vars_types - Cell array of selected variable data types.
            %
            % EXAMPLE USAGE:
            %   [names, types] = collect_vars(); % Default options
            %   [names, types] = collect_vars('multiselect', false, 'filters', @(x) startsWith(x.name, 'myVar'));
            %
            % SEE ALSO:
            %   data_loader_workspace
            %
            % Author: Lior de Marcas (LdM) https://github.com/Liordemarcas
            % Date: 231217

            arguments
                options.multiselect (1,1) logical {mustBeNumericOrLogical} = true;
                options.filters (1,1) function_handle = @(x) true(size(x,1),1);
            end

            % open loader
            app = data_loader_workspace('multiselect',options.multiselect,'filters',options.filters);
            
            % make sure to close app if exit on some reason
            cl = onCleanup(@() delete(app));

            % wait until window is closed to collect data
            uiwait(app.UIFigure)

            % collect vars info
            vars_names = app.UITable.Data{app.UITable.Selection,"name"};
            vars_types = app.UITable.Data{app.UITable.Selection,"class"};
            delete(app.UIFigure)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, options)
            % DATA_LOADER_WORKSPACE - App Designer Data Loader for Workspace Variables
            %
            %   This function serves as the data loader for an app created using MATLAB's
            %   App Designer. It collects information about the variables in the MATLAB
            %   base workspace, applies user-defined filters, and displays the filtered
            %   results in a UITable within the app. Users have the option to select multiple rows in the UITable.
            %   When finished, the function call uiresume instead of deleting - this let user collect output, than close it independently.
            %
            % SYNTAX:
            %   data_loader_workspace(app, varargin)
            %
            % INPUT ARGUMENTS:
            %   app         - The handle to the MATLAB App Designer app.
            %
            %   Name-Value Pairs:
            %
            %   'multiselect' - (default: true) A logical value indicating whether the
            %                   UITable should allow multiple row selections.
            %
            %   'filters'     - (default: @(x) true(size(x,1),1)) A function handle
            %                   defining the filtering criteria for the variables in
            %                   the base workspace. see FILTERS FUNCTION for more into.
            %
            % FILTERS FUNCTION:
            %   - The 'filters' function should be constructed to accept a table
            %     as input, with columns corresponding to the variable
            %     properties: 'name', 'class', 'size', and 'bytes'.
            %
            %   - The function should return a logical array of the same length as
            %     the input table, where each element indicates whether the
            %     corresponding variable should be retained (true) or excluded (false).
            %
            %   EXAMPLE FILTERS FUNCTION:
            %   ```
            %   function rows2keep = customFilter(variableTable)
            %       % Example: Retain variables of class 'double' with at least 1 row:
            %       rows2keep = strcmp(variableTable.class, 'double') & variableTable.size(1) > 1;
            %   end
            %   ```
            %
            % EXAMPLE USAGE:
            %   % Example 1: Using default options
            %   data_loader_workspace();
            %
            %   % Example 2: Customizing options
            %   data_loader_workspace('multiselect', false, 'filters', @(x) startsWith(x.name, 'myVar'));
            %
            % NOTES:
            %   - The function relies evalin('base','whos') to collect
            %     workspace info. Consider it for security implecation.
            %
            %   - When closing by close(app.UIFigure) (pressing X), the app
            %     simply call uiresume on the uifigure. This let user
            %     collect ouput on closing. Caller need to call delete(app.UIFigure)
            %     or delete(app) to close when finished.
            %     It is recommanded to use OnCleanup for best results.
            %
            %   - uifigure is modal, preventing interaction with other figures.
            %
            % SEE ALSO:
            %   evalin, whos
            %
            % Author: Lior de Marcas (LdM) https://github.com/Liordemarcas
            % Date: 231217


            arguments
                app
                options.multiselect (1,1) logical {mustBeNumericOrLogical} = true;
                options.filters (1,1) function_handle = @(x) true(size(x,1),1);
            end

            %%%%%% collect workspace variables %%%%%%%
            % taken from https://www.mathworks.com/matlabcentral/answers/504407-numeric-editfield-in-app-designer#answer_414538

            % Before calling the whos function from evalin, we'll do a
            % security check that the whos function is the one provided
            % by Matlab.  This reduces the risk of using evalin().
            if isempty(regexp(which('whos'), 'built-in *.', 'once'))
                error('The built-in whos() function is being overshaddowed.  Do not proceed.')
            end

            % Get base workspace variable info
            baseVarInfo = evalin('base', 'whos');
            % Convert to table
            baseVarInfo = rmfield(baseVarInfo,{'nesting','global','sparse','complex','persistent'});
            baseVarCell = struct2cell(baseVarInfo)'; % selection column
            baseVarTable = cell2table(baseVarCell, 'VariableNames',  fieldnames(baseVarInfo));
            baseVarTable = baseVarTable(:,["name","class","size","bytes"]);

            %%%%%% filter & push to uitable %%%%%%

            % make sure size is double
            if iscell(baseVarTable.size)
                % create init nan matrix
                nCols = cellfun(@(x) size(x,2), baseVarTable.size);
                new_size = nan(height(baseVarTable), max(nCols));

                for iRow = 1:height(baseVarTable)
                    currentVector = baseVarTable.size{iRow};
                    new_size(iRow, 1:numel(currentVector)) = currentVector;
                end

                baseVarTable.size = new_size;
            end

            % filter variables
            rows2keep = options.filters(baseVarTable);
            baseVarTable = baseVarTable(rows2keep,:);

            % convert size to text
            baseVarTable.size = rowfun(@(x) join(string(x(~isnan(x)))," x "),baseVarTable,"InputVariables",'size','OutputFormat','uniform');

            % insert data to table
            app.UITable.Data = baseVarTable;
            addStyle(app.UITable, uistyle("HorizontalAlignment","center"),"column",[2 3 4])
            if options.multiselect
                app.instructions.Text = [app.instructions.Text(1); {'Use ctrl to select multiple rows.'}; app.instructions.Text(2)];
                app.UITable.Multiselect = true;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % call uiresume, to let user collect data. User need to close
            % app independently, see 'collect_vars'
           
            uiresume(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Name'; 'Class'; 'Size'; 'Bytes'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.SelectionType = 'row';
            app.UITable.Multiselect = 'off';
            app.instructions = uilabel(app.GridLayout);
            app.instructions.Text = {'Choose Variable to read by clicking on the matching row.'; 'Close when finished.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_loader_workspace(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end